classdef smc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout6      matlab.ui.container.GridLayout
        Label_3          matlab.ui.control.Label
        StartButton      matlab.ui.control.Button
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        GridLayout       matlab.ui.container.GridLayout
        Label_2          matlab.ui.control.Label
        zaosheng         matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        shuru            matlab.ui.control.DropDown
        Tab_2            matlab.ui.container.Tab
        GridLayout2      matlab.ui.container.GridLayout
        tSpinner         matlab.ui.control.Spinner
        tSpinnerLabel    matlab.ui.control.Label
        cSpinner         matlab.ui.control.Spinner
        cSpinnerLabel    matlab.ui.control.Label
        epcSpinner       matlab.ui.control.Spinner
        epcSpinnerLabel  matlab.ui.control.Label
        JSpinner         matlab.ui.control.Spinner
        JSpinnerLabel    matlab.ui.control.Label
        TabGroup2        matlab.ui.container.TabGroup
        Tab_3            matlab.ui.container.Tab
        GridLayout4      matlab.ui.container.GridLayout
        UIAxes           matlab.ui.control.UIAxes
        Tab_4            matlab.ui.container.Tab
        GridLayout3      matlab.ui.container.GridLayout
        UIAxes_2         matlab.ui.control.UIAxes
        Tab_5            matlab.ui.container.Tab
        GridLayout5      matlab.ui.container.GridLayout
        UIAxes_3         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global J c epc d_flag s_flag
            J = app.JSpinner.Value;%转动惯量
            c = app.cSpinner.Value;%滑膜的Kp
            epc = app.epcSpinner.Value;%固定切换倍率，取决于执行器切换能力，电机速度控制的话epc可以给高；位置控制的话建议给低
            tf = app.tSpinner.Value;
            load_system('smc_demo.mdl');
            
            if app.zaosheng.Value == "随机信号"
                d_flag = 1;% 1是随机噪声 0是正弦函数噪声
                set_param('smc_demo/Constant','value','1')
            elseif app.zaosheng.Value == "正弦信号"
                d_flag = 0;
                set_param('smc_demo/Constant','value','0')
            end
            if app.shuru.Value == "阶跃输入"
                s_flag = 1;% 1是阶跃输入 0是正弦输入
                set_param('smc_demo/Constant1','value','1')
            elseif app.shuru.Value == "正弦输入"
                s_flag = 0;
                set_param('smc_demo/Constant1','value','0')
            end

            %% 运行模型
            
            sim('smc_demo.mdl',tf)
            %% 可视化
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            plot(app.UIAxes,t,y(:,4),'color',[0.5 0.5 0.5],'linewidth',0.02);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,t,y(:,1),'k',t,y(:,2),'r:','LineWidth',3)
            plot(app.UIAxes_2,t,u(:,1),'k','LineWidth',0.01)
            plot(app.UIAxes_3,e,de,'r','LineWidth',3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout6 = uigridlayout(app.UIFigure);
            app.TabGroup2 = uitabgroup(app.GridLayout6);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '输入响应曲线';
            app.GridLayout4 = uigridlayout(app.Tab_3);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '输入响应')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '信号幅值')
            zlabel(app.UIAxes, 'Z')
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '控制输入信号';
            app.GridLayout3 = uigridlayout(app.Tab_4);
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_2, '控制输入信号')
            xlabel(app.UIAxes_2, '时间')
            ylabel(app.UIAxes_2, '信号幅值')
            zlabel(app.UIAxes_2, 'Z')
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '相轨迹';
            app.GridLayout5 = uigridlayout(app.Tab_5);
            app.UIAxes_3 = uiaxes(app.GridLayout5);
            title(app.UIAxes_3, '相轨迹')
            xlabel(app.UIAxes_3, 'e')
            ylabel(app.UIAxes_3, 'de')
            zlabel(app.UIAxes_3, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout6);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '信号选择';
            app.GridLayout = uigridlayout(app.Tab);
            app.shuru = uidropdown(app.GridLayout);
            app.shuru.Items = {'阶跃输入', '正弦输入'};
            app.shuru.Value = '阶跃输入';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '输入信号: ';
            app.zaosheng = uidropdown(app.GridLayout);
            app.zaosheng.Items = {'随机信号', '正弦信号'};
            app.zaosheng.Value = '随机信号';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '噪声信号: ';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '参数设置';
            app.GridLayout2 = uigridlayout(app.Tab_2);
            app.JSpinnerLabel = uilabel(app.GridLayout2);
            app.JSpinnerLabel.Text = 'J';
            app.JSpinner = uispinner(app.GridLayout2);
            app.JSpinner.Step = 0.2;
            app.JSpinner.Limits = [0 5];
            app.JSpinner.Value = 1.5;
            app.epcSpinnerLabel = uilabel(app.GridLayout2);
            app.epcSpinnerLabel.Text = 'epc';
            app.epcSpinner = uispinner(app.GridLayout2);
            app.epcSpinner.Step = 0.2;
            app.epcSpinner.Limits = [0 5];
            app.epcSpinner.Value = 0.5;
            app.cSpinnerLabel = uilabel(app.GridLayout2);
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.GridLayout2);
            app.cSpinner.Step = 0.2;
            app.cSpinner.Limits = [0 5];
            app.cSpinner.Value = 1;
            app.tSpinnerLabel = uilabel(app.GridLayout2);
            app.tSpinnerLabel.Text = 't';
            app.tSpinner = uispinner(app.GridLayout2);
            app.tSpinner.Limits = [0.01 Inf];
            app.tSpinner.Value = 10;
            app.StartButton = uibutton(app.GridLayout6, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start!';
            app.Label_3 = uilabel(app.GridLayout6);
            app.Label_3.Text = '滑模控制器仿真';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smc_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end